classdef countrySelection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        AnalyzeButton        matlab.ui.control.Button
        ArgButton            matlab.ui.control.StateButton
        EcuButton            matlab.ui.control.StateButton
        ColButton            matlab.ui.control.StateButton
        VenButton            matlab.ui.control.StateButton
        BraButton            matlab.ui.control.StateButton
        PanButton            matlab.ui.control.StateButton
        NicButton            matlab.ui.control.StateButton
        HonButton            matlab.ui.control.StateButton
        MexButton            matlab.ui.control.StateButton
        CentralAmericaImage  matlab.ui.control.Image
        SouthAmericaImage    matlab.ui.control.Image
        GeoGameButton        matlab.ui.control.StateButton
        TwoCountryButton     matlab.ui.control.StateButton
        SingleCountryButton  matlab.ui.control.StateButton
    end

    
    properties (Access = private)
        selectable = 1; % Description
        selected = [0,0];
    end
    
    methods (Access = private)
        
        % Switches the value of the header buttons based on selected input
        % so that only one is selected at a time
        function headerButtons(app,selected)
            switch selected
                case 1 % Single Country
                    app.SingleCountryButton.Value = true;
                    app.TwoCountryButton.Value = false;
                    app.GeoGameButton.Value = false;
                    app.selectable = 1;
                    app.selected = [0,0];
                    changeCountryButtons(app);
                case 2 % Two Country
                    app.SingleCountryButton.Value = false;
                    app.TwoCountryButton.Value = true;
                    app.GeoGameButton.Value = false;
                    app.selectable = 2;
                    app.selected = [0,0];
                    changeCountryButtons(app);
                case 3 % Geo Game
                    app.SingleCountryButton.Value = false;
                    app.TwoCountryButton.Value = false;
                    app.GeoGameButton.Value = true;
            end
        end

        % Switches the value of the slected array to reflect the selected
        % country/countries based on single/two country comparisons
        function countryButtons(app,selected)
            % Changes selected array
            if app.selectable == 1
                app.selected = [selected,0];
            else
                app.selected(1) = app.selected(2);
                app.selected(2) = selected;
            end
            changeCountryButtons(app);
        end

        % Changes the values of all the buttons based on the selected
        % countries variable
        function changeCountryButtons(app)
            % Changes button values
            if app.selected(1) == 1 || app.selected(2) == 1
                app.MexButton.Value = true;
            else
                app.MexButton.Value = false;
            end
            if app.selected(1) == 2 || app.selected(2) == 2
                app.HonButton.Value = true;
            else
                app.HonButton.Value = false;
            end
            if app.selected(1) == 3 || app.selected(2) == 3
                app.NicButton.Value = true;
            else
                app.NicButton.Value = false;
            end
            if app.selected(1) == 4 || app.selected(2) == 4
                app.PanButton.Value = true;
            else
                app.PanButton.Value = false;
            end
            if app.selected(1) == 5 || app.selected(2) == 5
                app.BraButton.Value = true;
            else
                app.BraButton.Value = false;
            end
            if app.selected(1) == 6 || app.selected(2) == 6
                app.VenButton.Value = true;
            else
                app.VenButton.Value = false;
            end
            if app.selected(1) == 7 || app.selected(2) == 7
                app.ColButton.Value = true;
            else
                app.ColButton.Value = false;
            end
            if app.selected(1) == 8 || app.selected(2) == 8
                app.EcuButton.Value = true;
            else
                app.EcuButton.Value = false;
            end
            if app.selected(1) == 9 || app.selected(2) == 9
                app.ArgButton.Value = true;
            else
                app.ArgButton.Value = false;
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: SingleCountryButton
        function SingleCountryButtonValueChanged(app, event)
            headerButtons(app,1);
        end

        % Value changed function: TwoCountryButton
        function TwoCountryButtonValueChanged(app, event)
            headerButtons(app,2);
        end

        % Value changed function: GeoGameButton
        function GeoGameButtonValueChanged(app, event)
            headerButtons(app,3);
            guessingGame;
            pause(1);
            SingleCountryButtonValueChanged(app,true);
        end

        % Value changed function: MexButton
        function MexButtonValueChanged(app, event)
            countryButtons(app,1);
        end

        % Value changed function: HonButton
        function HonButtonValueChanged(app, event)
            countryButtons(app,2);
        end

        % Value changed function: NicButton
        function NicButtonValueChanged(app, event)
            countryButtons(app,3);
        end

        % Value changed function: PanButton
        function PanButtonValueChanged(app, event)
            countryButtons(app,4);
        end

        % Value changed function: BraButton
        function BraButtonValueChanged(app, event)
            countryButtons(app,5);
        end

        % Value changed function: VenButton
        function VenButtonValueChanged(app, event)
            countryButtons(app,6);
        end

        % Value changed function: ColButton
        function ColButtonValueChanged(app, event)
            countryButtons(app,7);
        end

        % Value changed function: EcuButton
        function EcuButtonValueChanged(app, event)
            countryButtons(app,8);
        end

        % Value changed function: ArgButton
        function ArgButtonValueChanged(app, event)
            countryButtons(app,9);
        end

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)
           if (app.selected(1) == 0) % Does nothing
           elseif (app.selected(2) == 0)
               question3project.updateData(question3project,app.selected);
           else
               question2project.updateData(question2project,app.selected);
           end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SingleCountryButton = uibutton(app.UIFigure, 'state');
            app.SingleCountryButton.ValueChangedFcn = createCallbackFcn(app, @SingleCountryButtonValueChanged, true);
            app.SingleCountryButton.Text = 'Single Country Analysis';
            app.SingleCountryButton.Value = true;
            app.TwoCountryButton = uibutton(app.UIFigure, 'state');
            app.TwoCountryButton.ValueChangedFcn = createCallbackFcn(app, @TwoCountryButtonValueChanged, true);
            app.TwoCountryButton.Text = 'Two-Country Comparison';
            app.GeoGameButton = uibutton(app.UIFigure, 'state');
            app.GeoGameButton.ValueChangedFcn = createCallbackFcn(app, @GeoGameButtonValueChanged, true);
            app.GeoGameButton.Text = 'Geography Game';
            app.SouthAmericaImage = uiimage(app.UIFigure);
            app.SouthAmericaImage.ImageSource = fullfile(pathToMLAPP, 'Images', 'South America Map.png');
            app.CentralAmericaImage = uiimage(app.UIFigure);
            app.CentralAmericaImage.ImageSource = fullfile(pathToMLAPP, 'Images', 'Central America.png');
            app.MexButton = uibutton(app.UIFigure, 'state');
            app.MexButton.ValueChangedFcn = createCallbackFcn(app, @MexButtonValueChanged, true);
            app.MexButton.Icon = fullfile(pathToMLAPP, 'Images', 'CountryFlags', 'Mexico.png');
            app.MexButton.Text = '';
            app.HonButton = uibutton(app.UIFigure, 'state');
            app.HonButton.ValueChangedFcn = createCallbackFcn(app, @HonButtonValueChanged, true);
            app.HonButton.Icon = fullfile(pathToMLAPP, 'Images', 'CountryFlags', 'Honduras.png');
            app.HonButton.Text = '';
            app.NicButton = uibutton(app.UIFigure, 'state');
            app.NicButton.ValueChangedFcn = createCallbackFcn(app, @NicButtonValueChanged, true);
            app.NicButton.Icon = fullfile(pathToMLAPP, 'Images', 'CountryFlags', 'Nicaragua.png');
            app.NicButton.Text = '';
            app.PanButton = uibutton(app.UIFigure, 'state');
            app.PanButton.ValueChangedFcn = createCallbackFcn(app, @PanButtonValueChanged, true);
            app.PanButton.Icon = fullfile(pathToMLAPP, 'Images', 'CountryFlags', 'Panama.png');
            app.PanButton.Text = '';
            app.BraButton = uibutton(app.UIFigure, 'state');
            app.BraButton.ValueChangedFcn = createCallbackFcn(app, @BraButtonValueChanged, true);
            app.BraButton.Icon = fullfile(pathToMLAPP, 'Images', 'CountryFlags', 'Brazil.png');
            app.BraButton.Text = '';
            app.VenButton = uibutton(app.UIFigure, 'state');
            app.VenButton.ValueChangedFcn = createCallbackFcn(app, @VenButtonValueChanged, true);
            app.VenButton.Icon = fullfile(pathToMLAPP, 'Images', 'CountryFlags', 'Venezuela.png');
            app.VenButton.Text = '';
            app.ColButton = uibutton(app.UIFigure, 'state');
            app.ColButton.ValueChangedFcn = createCallbackFcn(app, @ColButtonValueChanged, true);
            app.ColButton.Icon = fullfile(pathToMLAPP, 'Images', 'CountryFlags', 'Colombia.png');
            app.ColButton.Text = '';
            app.EcuButton = uibutton(app.UIFigure, 'state');
            app.EcuButton.ValueChangedFcn = createCallbackFcn(app, @EcuButtonValueChanged, true);
            app.EcuButton.Icon = fullfile(pathToMLAPP, 'Images', 'CountryFlags', 'Ecuador.png');
            app.EcuButton.Text = '';
            app.ArgButton = uibutton(app.UIFigure, 'state');
            app.ArgButton.ValueChangedFcn = createCallbackFcn(app, @ArgButtonValueChanged, true);
            app.ArgButton.Icon = fullfile(pathToMLAPP, 'Images', 'CountryFlags', 'Argentina.png');
            app.ArgButton.Text = '';
            app.AnalyzeButton = uibutton(app.UIFigure, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Text = 'Analyze!';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = countrySelection

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end