classdef guessingGame < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        EndButton            matlab.ui.control.Button
        SubmitButton         matlab.ui.control.Button
        IncorrectLabel       matlab.ui.control.Label
        CorrectLabel         matlab.ui.control.Label
        GuessField           matlab.ui.control.EditField
        WhatCountryisthisEditFieldLabel  matlab.ui.control.Label
        GoBackButton         matlab.ui.control.Button
        TitleText            matlab.ui.control.Label
        ShowFlagBox          matlab.ui.control.CheckBox
        FlagImage            matlab.ui.control.Image
        MapImage             matlab.ui.control.Image
        ScoreField           matlab.ui.control.EditField
        ScoreEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
       countries = ["Mexico", "Honduras", "Nicaragua", "Panama", "Brazil",... 
                    "Venezuela", "Colombia", "Ecuador", "Argentina", "Chile", "Paraguay",...
                    "El Salvador", "Puerto Rico", "Dominican Republic", "Bolivia",...
                    "Costa Rica", "Cuba", "Guatemala", "Peru", "Uruguay"];
       selected = zeros(1,20);
       answer;
       score = 0;
       round = 1;
    end
    
    methods (Access = private)
        

    end
    
    methods (Access = public)

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            [app.answer,previous] = generateCountry(app);
            app.selected(app.round) = previous;
            app.ScoreField.Value = string(app.score) + "/";
        end

        % Value changed function: ShowFlagBox
        function ShowFlagBoxValueChanged(app, event)
            value = app.ShowFlagBox.Value;
            if (value)
                app.FlagImage.Visible ="on";
            else
                app.FlagImage.Visible ="off";
            end
        end

        % Button pushed function: GoBackButton
        function GoBackButtonPushed(app, event)
            app.delete
        end

        % Button pushed function: SubmitButton
        function SubmitButtonPushed(app, event)
            guess = app.GuessField.Value;
            if strcmpi(guess,app.answer)
                app.CorrectLabel.Visible = "on";
                app.IncorrectLabel.Visible = "off";
                app.score = app.score + 1;
            else
                app.CorrectLabel.Visible = "off";
                app.IncorrectLabel.Visible = "on";
            end
            app.ScoreField.Value = string(app.score) + "/" + string(app.round);
            app.round = app.round + 1;
            if app.round ~= 21
                [app.answer,previous] = generateCountry(app);
                app.selected(app.round) = previous;
                app.GuessField.Value = "";
                app.ShowFlagBox.Value = false;
                app.FlagImage.Visible = "off";
            else
                app.EndButton.Text = "Final Score: " + app.ScoreField.Value;
                app.EndButton.Visible = "on";
            end
        end

        % Button pushed function: EndButton
        function EndButtonPushed(app, event)
            app.delete;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ScoreEditFieldLabel = uilabel(app.UIFigure);
            app.ScoreEditFieldLabel.Text = 'Score:';
            app.ScoreField = uieditfield(app.UIFigure, 'text');
            app.ScoreField.Editable = 'off';
            app.MapImage = uiimage(app.UIFigure);
            app.FlagImage = uiimage(app.UIFigure);
            app.FlagImage.Visible = 'off';
            app.ShowFlagBox = uicheckbox(app.UIFigure);
            app.ShowFlagBox.ValueChangedFcn = createCallbackFcn(app, @ShowFlagBoxValueChanged, true);
            app.ShowFlagBox.Text = 'Show Country Flag?';
            app.TitleText = uilabel(app.UIFigure);
            app.TitleText.Text = 'Geography Guessing Game';
            app.GoBackButton = uibutton(app.UIFigure, 'push');
            app.GoBackButton.ButtonPushedFcn = createCallbackFcn(app, @GoBackButtonPushed, true);
            app.GoBackButton.Text = 'Go Back';
            app.WhatCountryisthisEditFieldLabel = uilabel(app.UIFigure);
            app.WhatCountryisthisEditFieldLabel.Text = 'What Country is this?';
            app.GuessField = uieditfield(app.UIFigure, 'text');
            app.CorrectLabel = uilabel(app.UIFigure);
            app.CorrectLabel.Visible = 'off';
            app.CorrectLabel.Text = 'Correct!';
            app.IncorrectLabel = uilabel(app.UIFigure);
            app.IncorrectLabel.Visible = 'off';
            app.IncorrectLabel.Text = 'Incorrect!';
            app.SubmitButton = uibutton(app.UIFigure, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.SubmitButton.Text = 'Submit';
            app.EndButton = uibutton(app.UIFigure, 'push');
            app.EndButton.ButtonPushedFcn = createCallbackFcn(app, @EndButtonPushed, true);
            app.EndButton.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = guessingGame

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end