classdef question2project < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        STARTButton                    matlab.ui.control.Button
        Label                          matlab.ui.control.Label
        FromyearEditField              matlab.ui.control.NumericEditField
        FromyearEditFieldLabel         matlab.ui.control.Label
        ToyearEditField                matlab.ui.control.NumericEditField
        ToEditFieldLabel               matlab.ui.control.Label
        RecommendedComparisonsListBox  matlab.ui.control.ListBox
        RecommendedComparisonsLabel    matlab.ui.control.Label
        VSLabel                        matlab.ui.control.Label
        Image1_Double                  matlab.ui.control.Image
        Image2_Double                  matlab.ui.control.Image
        GOBACKButton_Double            matlab.ui.control.Button
        UIAxes2_Double                 matlab.ui.control.UIAxes
        UIAxes1_Double                 matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        selectedCountries; % Description
        min = 2000; % Description
        max = 2022;
        filtered_data_Country1;
        filtered_data_Country2;
    end
    
    methods (Access = public)
        
        function onDataReceived(app, data)
            app.selectedCountries = data;
            disp(app.selectedCountries);
            
        end
    end
    
    methods (Access = public, Static)
        
        function updateData(app,data)
            app.onDataReceived(data);
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: FromyearEditField
        function FromyearEditFieldValueChanged(app, event)
            value = app.FromyearEditField.Value;
            if value<app.min
                app.FromyearEditField.Value = app.min;
            elseif value>app.max
                app.FromyearEditField.Value = app.max;
            end

            app.UIAxes1_Double.XLim = [app.FromyearEditField.Value,app.ToyearEditField.Value];
            
            app.UIAxes1_Double.XTick = linspace(app.FromyearEditField.Value,app.ToyearEditField.Value,app.ToyearEditField.Value-app.FromyearEditField.Value+1);

            app.UIAxes1_Double.XTickLabel = linspace(app.FromyearEditField.Value,app.ToyearEditField.Value,app.ToyearEditField.Value-app.FromyearEditField.Value+1);

            app.UIAxes2_Double.XLim = [app.FromyearEditField.Value,app.ToyearEditField.Value];

            app.UIAxes2_Double.XTick = linspace(app.FromyearEditField.Value,app.ToyearEditField.Value,app.ToyearEditField.Value-app.FromyearEditField.Value+1);

            app.UIAxes2_Double.XTickLabel = linspace(app.FromyearEditField.Value,app.ToyearEditField.Value,app.ToyearEditField.Value-app.FromyearEditField.Value+1);
            
        end

        % Value changed function: ToyearEditField
        function ToyearEditFieldValueChanged(app, event)
            value = app.ToyearEditField.Value;
            if value<app.min
                app.ToyearEditField.Value = app.min;
            elseif value>app.max
                app.ToyearEditField.Value = app.max;
            end
            
            app.UIAxes1_Double.XLim = [app.FromyearEditField.Value,app.ToyearEditField.Value];

            app.UIAxes1_Double.XTick = linspace(app.FromyearEditField.Value,app.ToyearEditField.Value,app.ToyearEditField.Value-app.FromyearEditField.Value+1);

            app.UIAxes1_Double.XTickLabel = linspace(app.FromyearEditField.Value,app.ToyearEditField.Value,app.ToyearEditField.Value-app.FromyearEditField.Value+1);

            app.UIAxes2_Double.XLim = [app.FromyearEditField.Value,app.ToyearEditField.Value];

            app.UIAxes2_Double.XTick = linspace(app.FromyearEditField.Value,app.ToyearEditField.Value,app.ToyearEditField.Value-app.FromyearEditField.Value+1);

            app.UIAxes2_Double.XTickLabel = linspace(app.FromyearEditField.Value,app.ToyearEditField.Value,app.ToyearEditField.Value-app.FromyearEditField.Value+1);
        end

        % Value changed function: RecommendedComparisonsListBox
        function RecommendedComparisonsListBoxValueChanged(app, event)
            app.FromyearEditField.Value = app.min;
            app.ToyearEditField.Value = app.max;

            app.UIAxes1_Double.XLim = [app.FromyearEditField.Value,app.ToyearEditField.Value];

            app.UIAxes1_Double.XTick = linspace(app.FromyearEditField.Value,app.ToyearEditField.Value,app.ToyearEditField.Value-app.FromyearEditField.Value+1);

            app.UIAxes1_Double.XTickLabel = linspace(app.FromyearEditField.Value,app.ToyearEditField.Value,app.ToyearEditField.Value-app.FromyearEditField.Value+1);

            app.UIAxes2_Double.XLim = [app.FromyearEditField.Value,app.ToyearEditField.Value];

            app.UIAxes2_Double.XTick = linspace(app.FromyearEditField.Value,app.ToyearEditField.Value,app.ToyearEditField.Value-app.FromyearEditField.Value+1);
            
            app.UIAxes2_Double.XTickLabel = linspace(app.FromyearEditField.Value,app.ToyearEditField.Value,app.ToyearEditField.Value-app.FromyearEditField.Value+1);

            Indicators_1(app);

        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            [app.filtered_data_Country1, app.filtered_data_Country2] = CountryFilter_1(app);
            %RecommendedComparisonsListBoxValueChanged(app,)
            app.STARTButton.Visible = "off";
        end

        % Button pushed function: GOBACKButton_Double
        function GOBACKButton_DoublePushed(app, event)
            app.delete
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes1_Double = uiaxes(app.UIFigure);
            xlabel(app.UIAxes1_Double, 'Year ')
            ylabel(app.UIAxes1_Double, 'Y')
            zlabel(app.UIAxes1_Double, 'Z')
            app.UIAxes1_Double.XLim = [2000 2022];
            app.UIAxes1_Double.YLim = [-Inf Inf];
            app.UIAxes1_Double.XTick = [2000 2001 2002 2003 2004 2005 2006 2007 2008 2009 2010 2011 2012 2013 2014 2015 2016 2017 2018 2019 2020 2021 2022];
            app.UIAxes1_Double.XTickLabel = {'2000'; '2001'; '2002'; '2003'; '2004'; '2005'; '2006'; '2007'; '2008'; '2009'; '2010'; '2011'; '2012'; '2013'; '2014'; '2015'; '2016'; '2017'; '2018'; '2019'; '2020'; '2021'; '2022'};
            app.UIAxes2_Double = uiaxes(app.UIFigure);
            xlabel(app.UIAxes2_Double, 'Year ')
            ylabel(app.UIAxes2_Double, 'Y')
            zlabel(app.UIAxes2_Double, 'Z')
            app.UIAxes2_Double.XLim = [2000 2022];
            app.UIAxes2_Double.YLim = [-Inf Inf];
            app.UIAxes2_Double.XTick = [2000 2001 2002 2003 2004 2005 2006 2007 2008 2009 2010 2011 2012 2013 2014 2015 2016 2017 2018 2019 2020 2021 2022];
            app.UIAxes2_Double.XTickLabel = {'2000'; '2001'; '2002'; '2003'; '2004'; '2005'; '2006'; '2007'; '2008'; '2009'; '2010'; '2011'; '2012'; '2013'; '2014'; '2015'; '2016'; '2017'; '2018'; '2019'; '2020'; '2021'; '2022'};
            app.GOBACKButton_Double = uibutton(app.UIFigure, 'push');
            app.GOBACKButton_Double.ButtonPushedFcn = createCallbackFcn(app, @GOBACKButton_DoublePushed, true);
            app.GOBACKButton_Double.Text = 'GO BACK';
            app.Image2_Double = uiimage(app.UIFigure);
            app.Image1_Double = uiimage(app.UIFigure);
            app.VSLabel = uilabel(app.UIFigure);
            app.VSLabel.Text = 'VS';
            app.RecommendedComparisonsLabel = uilabel(app.UIFigure);
            app.RecommendedComparisonsLabel.Text = 'Recommended Comparisons:';
            app.RecommendedComparisonsListBox = uilistbox(app.UIFigure);
            app.RecommendedComparisonsListBox.Items = {'Overall performance (0-100)', 'GDP growth (annual %)', 'GDP per capita (current US$)', 'Unemployment (% of labor force)', 'Population total (people)', 'Life expectancy at birth (years)', 'Net migration (people)'};
            app.RecommendedComparisonsListBox.ValueChangedFcn = createCallbackFcn(app, @RecommendedComparisonsListBoxValueChanged, true);
            app.RecommendedComparisonsListBox.Value = {};
            app.ToEditFieldLabel = uilabel(app.UIFigure);
            app.ToEditFieldLabel.Text = 'To:';
            app.ToyearEditField = uieditfield(app.UIFigure, 'numeric');
            app.ToyearEditField.Limits = [2000 2022];
            app.ToyearEditField.ValueChangedFcn = createCallbackFcn(app, @ToyearEditFieldValueChanged, true);
            app.ToyearEditField.Value = 2022;
            app.FromyearEditFieldLabel = uilabel(app.UIFigure);
            app.FromyearEditFieldLabel.Text = 'From year:';
            app.FromyearEditField = uieditfield(app.UIFigure, 'numeric');
            app.FromyearEditField.Limits = [2000 2022];
            app.FromyearEditField.ValueChangedFcn = createCallbackFcn(app, @FromyearEditFieldValueChanged, true);
            app.FromyearEditField.Value = 2000;
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '(2000-2022)';
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = question2project

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end