classdef question3project < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Indicator2DropDown              matlab.ui.control.DropDown
        Indicator1DropDown              matlab.ui.control.DropDown
        STARTButton                     matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        ToyearEditField                 matlab.ui.control.NumericEditField
        ToEditFieldLabel                matlab.ui.control.Label
        FromyearEditField               matlab.ui.control.NumericEditField
        FromyearEditFieldLabel          matlab.ui.control.Label
        RecommendedCorrelationsListBox  matlab.ui.control.ListBox
        RecommendedComparisonsLabel     matlab.ui.control.Label
        Image_Single                    matlab.ui.control.Image
        GOBACKButton_Single             matlab.ui.control.Button
        UIAxes1_Single                  matlab.ui.control.UIAxes
        UIAxes2_Single                  matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        selectedCountries; % Description
        min = 2000; % Description
        max = 2022;
        filtered_data_Country;
    end
    
    methods (Access = public)
        
        function onDataReceived(app, data)
            app.selectedCountries = data;
            disp(app.selectedCountries);
            
        end
    end
    
    methods (Access = public, Static)
        
        function updateData(app,data)
            app.onDataReceived(data);
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Indicator2DropDown.Visible = "off";
            app.Indicator1DropDown.Visible = "off";
            
        end

        % Value changed function: FromyearEditField
        function FromyearEditFieldValueChanged(app, event)
            value = app.FromyearEditField.Value;
            if value<app.min
                app.FromyearEditField.Value = app.min;
            elseif value>app.max
                app.FromyearEditField.Value = app.max;
            end

            app.UIAxes1_Single.XLim = [app.FromyearEditField.Value,app.ToyearEditField.Value];
            
            app.UIAxes1_Single.XTick = linspace(app.FromyearEditField.Value,app.ToyearEditField.Value,app.ToyearEditField.Value-app.FromyearEditField.Value+1);

            app.UIAxes1_Single.XTickLabel = linspace(app.FromyearEditField.Value,app.ToyearEditField.Value,app.ToyearEditField.Value-app.FromyearEditField.Value+1);

            app.UIAxes2_Single.XLim = [app.FromyearEditField.Value,app.ToyearEditField.Value];

            app.UIAxes2_Single.XTick = linspace(app.FromyearEditField.Value,app.ToyearEditField.Value,app.ToyearEditField.Value-app.FromyearEditField.Value+1);

            app.UIAxes2_Single.XTickLabel = linspace(app.FromyearEditField.Value,app.ToyearEditField.Value,app.ToyearEditField.Value-app.FromyearEditField.Value+1);
        end

        % Value changed function: ToyearEditField
        function ToyearEditFieldValueChanged(app, event)
            value = app.ToyearEditField.Value;
            if value<app.min
                app.ToyearEditField.Value = app.min;
            elseif value>app.max
                app.ToyearEditField.Value = app.max;
            end
            
            app.UIAxes1_Single.XLim = [app.FromyearEditField.Value,app.ToyearEditField.Value];

            app.UIAxes1_Single.XTick = linspace(app.FromyearEditField.Value,app.ToyearEditField.Value,app.ToyearEditField.Value-app.FromyearEditField.Value+1);

            app.UIAxes1_Single.XTickLabel = linspace(app.FromyearEditField.Value,app.ToyearEditField.Value,app.ToyearEditField.Value-app.FromyearEditField.Value+1);

            app.UIAxes2_Single.XLim = [app.FromyearEditField.Value,app.ToyearEditField.Value];

            app.UIAxes2_Single.XTick = linspace(app.FromyearEditField.Value,app.ToyearEditField.Value,app.ToyearEditField.Value-app.FromyearEditField.Value+1);

            app.UIAxes2_Single.XTickLabel = linspace(app.FromyearEditField.Value,app.ToyearEditField.Value,app.ToyearEditField.Value-app.FromyearEditField.Value+1);
        end

        % Value changed function: RecommendedCorrelationsListBox
        function RecommendedCorrelationsListBoxValueChanged(app, event)
            app.FromyearEditField.Value = app.min;
            app.ToyearEditField.Value = app.max;

            app.UIAxes1_Single.XLim = [app.FromyearEditField.Value,app.ToyearEditField.Value];

            app.UIAxes1_Single.XTick = linspace(app.FromyearEditField.Value,app.ToyearEditField.Value,app.ToyearEditField.Value-app.FromyearEditField.Value+1);

            app.UIAxes1_Single.XTickLabel = linspace(app.FromyearEditField.Value,app.ToyearEditField.Value,app.ToyearEditField.Value-app.FromyearEditField.Value+1);

            app.UIAxes2_Single.XLim = [app.FromyearEditField.Value,app.ToyearEditField.Value];

            app.UIAxes2_Single.XTick = linspace(app.FromyearEditField.Value,app.ToyearEditField.Value,app.ToyearEditField.Value-app.FromyearEditField.Value+1);
            
            app.UIAxes2_Single.XTickLabel = linspace(app.FromyearEditField.Value,app.ToyearEditField.Value,app.ToyearEditField.Value-app.FromyearEditField.Value+1);
            
            if ~strcmp(app.RecommendedCorrelationsListBox.Value,"Choose your own")
                Indicators_2(app);
                app.Indicator1DropDown.Visible = "off";
                app.Indicator2DropDown.Visible = "off";
            else 
                app.Indicator1DropDown.Visible = "on";
                app.Indicator2DropDown.Visible = "on";
            end

        end

        % Button pushed function: GOBACKButton_Single
        function GOBACKButton_SinglePushed(app, event)
            app.delete
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            [app.filtered_data_Country] = CountryFilter_2(app);
            %RecommendedComparisonsListBoxValueChanged(app,)
            app.STARTButton.Visible = "off";
            CountryFilter_2(app)
        end

        % Value changed function: Indicator1DropDown
        function Indicator1DropDownValueChanged(app, event)
           
            Indicators_2(app);
        end

        % Value changed function: Indicator2DropDown
        function Indicator2DropDownValueChanged(app, event)
            
            Indicators_2(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes2_Single = uiaxes(app.UIFigure);
            title(app.UIAxes2_Single, 'Title')
            xlabel(app.UIAxes2_Single, 'Year')
            ylabel(app.UIAxes2_Single, 'Y')
            zlabel(app.UIAxes2_Single, 'Z')
            app.UIAxes2_Single.XLim = [2000 2022];
            app.UIAxes2_Single.XTick = [2000 2001 2002 2003 2004 2005 2006 2007 2008 2009 2010 2011 2012 2013 2014 2015 2016 2017 2018 2019 2020 2021 2022];
            app.UIAxes2_Single.XTickLabel = {'2000'; '2001'; '2002'; '2003'; '2004'; '2005'; '2006'; '2007'; '2008'; '2009'; '2010'; '2011'; '2012'; '2013'; '2014'; '2015'; '2016'; '2017'; '2018'; '2019'; '2020'; '2021'; '2022'};
            app.UIAxes1_Single = uiaxes(app.UIFigure);
            title(app.UIAxes1_Single, 'Title')
            xlabel(app.UIAxes1_Single, 'Year')
            ylabel(app.UIAxes1_Single, 'Y')
            zlabel(app.UIAxes1_Single, 'Z')
            app.UIAxes1_Single.XLim = [2000 2022];
            app.UIAxes1_Single.XTick = [2000 2001 2002 2003 2004 2005 2006 2007 2008 2009 2010 2011 2012 2013 2014 2015 2016 2017 2018 2019 2020 2021 2022];
            app.UIAxes1_Single.XTickLabel = {'2000'; '2001'; '2002'; '2003'; '2004'; '2005'; '2006'; '2007'; '2008'; '2009'; '2010'; '2011'; '2012'; '2013'; '2014'; '2015'; '2016'; '2017'; '2018'; '2019'; '2020'; '2021'; '2022'};
            app.GOBACKButton_Single = uibutton(app.UIFigure, 'push');
            app.GOBACKButton_Single.ButtonPushedFcn = createCallbackFcn(app, @GOBACKButton_SinglePushed, true);
            app.GOBACKButton_Single.Text = 'GO BACK';
            app.Image_Single = uiimage(app.UIFigure);
            app.RecommendedComparisonsLabel = uilabel(app.UIFigure);
            app.RecommendedComparisonsLabel.Text = 'Recommended Correlations:';
            app.RecommendedCorrelationsListBox = uilistbox(app.UIFigure);
            app.RecommendedCorrelationsListBox.Items = {'Population growth and unemployment', 'Foreign direct investment and gov. debt', 'Access to electricity and renewable production', 'Inflation and corruption', 'Forest area and CO2 emissions', 'Choose your own'};
            app.RecommendedCorrelationsListBox.ValueChangedFcn = createCallbackFcn(app, @RecommendedCorrelationsListBoxValueChanged, true);
            app.RecommendedCorrelationsListBox.Value = {};
            app.FromyearEditFieldLabel = uilabel(app.UIFigure);
            app.FromyearEditFieldLabel.Text = 'From year:';
            app.FromyearEditField = uieditfield(app.UIFigure, 'numeric');
            app.FromyearEditField.Limits = [2000 2022];
            app.FromyearEditField.ValueChangedFcn = createCallbackFcn(app, @FromyearEditFieldValueChanged, true);
            app.FromyearEditField.Value = 2000;
            app.ToEditFieldLabel = uilabel(app.UIFigure);
            app.ToEditFieldLabel.Text = 'To:';
            app.ToyearEditField = uieditfield(app.UIFigure, 'numeric');
            app.ToyearEditField.Limits = [2000 2022];
            app.ToyearEditField.ValueChangedFcn = createCallbackFcn(app, @ToyearEditFieldValueChanged, true);
            app.ToyearEditField.Value = 2022;
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '(2000-2022)';
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.Indicator1DropDown = uidropdown(app.UIFigure);
            app.Indicator1DropDown.Items = {'Population growth (annual %)', 'Net migration (people)', 'Population total (people)', 'Unemployment (% of labor force)', 'Annual freshwater withdrawals, total (% of internal resources)', 'Foreign direct investment (% of GDP)', 'Intentional homicides (per 100,000 people)', 'Life expectancy at birth (years)', 'GDP per capita (current US$)', 'Forest area (% of land area)', 'Contributing family workers, total (% of total employment) (modeled ILO estimate)', 'CO2 emissions (metric tons pp)', 'Overall performance (0-100)', 'Renewable electricty (% of total)', 'Poverty gap at $2.15 a day (2017 PPP) (%)', 'Human capital index (HCI) (scale 0-1)', 'Proportion of seats held by women in national parliaments (%)', 'Electricity access (% of population)', 'People using safely managed sanitation services (% of population)', 'Personal remittances, received (% of GDP)', 'GDP growth (annual %)', 'GDP (current US$)', 'Government debt (% of GDP)', 'Inflation (annual %)', 'Control of Corruption (-2.5 to 2.5)'};
            app.Indicator1DropDown.ValueChangedFcn = createCallbackFcn(app, @Indicator1DropDownValueChanged, true);
            app.Indicator1DropDown.Value = 'Population growth (annual %)';
            app.Indicator2DropDown = uidropdown(app.UIFigure);
            app.Indicator2DropDown.Items = {'Population growth (annual %)', 'Net migration (people)', 'Population total (people)', 'Unemployment (% of labor force)', 'Annual freshwater withdrawals, total (% of internal resources)', 'Foreign direct investment (% of GDP)', 'Intentional homicides (per 100,000 people)', 'Life expectancy at birth (years)', 'GDP per capita (current US$)', 'Forest area (% of land area)', 'Contributing family workers, total (% of total employment) (modeled ILO estimate)', 'CO2 emissions (metric tons pp)', 'Overall performance (0-100)', 'Renewable electricty (% of total)', 'Poverty gap at $2.15 a day (2017 PPP) (%)', 'Human capital index (HCI) (scale 0-1)', 'Proportion of seats held by women in national parliaments (%)', 'Electricity access (% of population)', 'People using safely managed sanitation services (% of population)', 'Personal remittances, received (% of GDP)', 'GDP growth (annual %)', 'GDP (current US$)', 'Government debt (% of GDP)', 'Inflation (annual %)', 'Control of Corruption (-2.5 to 2.5)'};
            app.Indicator2DropDown.ValueChangedFcn = createCallbackFcn(app, @Indicator2DropDownValueChanged, true);
            app.Indicator2DropDown.Value = 'Population growth (annual %)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = question3project

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end